classdef beepbeep < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Label                  matlab.ui.control.Label
        DropDown               matlab.ui.control.DropDown
        Label_2                matlab.ui.control.Label
        Spinner                matlab.ui.control.Spinner
        msEditFieldLabel       matlab.ui.control.Label
        msEditField            matlab.ui.control.NumericEditField
        BeepdAAdBBmmLabel      matlab.ui.control.Label
        BeepdAAdBBmmEditField  matlab.ui.control.NumericEditField
        TextAreaLabel          matlab.ui.control.Label
        TextArea               matlab.ui.control.TextArea
        start_calc_dist        matlab.ui.control.Button
        refresh_devices        matlab.ui.control.Button
        Label_4                matlab.ui.control.Label
        f0_edit                matlab.ui.control.NumericEditField
        Label_5                matlab.ui.control.Label
        f1_edit                matlab.ui.control.NumericEditField
        Label_6                matlab.ui.control.Label
        windows_size_edit      matlab.ui.control.NumericEditField
        Label_7                matlab.ui.control.Label
        fs_edit                matlab.ui.control.NumericEditField
        Label_8                matlab.ui.control.Label
        FSK_code               matlab.ui.control.EditField
        send_wav_file_button   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            global i_channel;
            app.Spinner.Value = i_channel(app.DropDown.Value);
        end

        % Button pushed function: refresh_devices
        function refresh_devicesPushed(app, event)
            global i_channel;
            [~,message] = system('adb devices -l');
            devices = splitlines(message);
            devices = devices(2:end-2);
            app.DropDown.Items = devices;
            app.DropDown.ItemsData = 1:1:length(devices);
            i_channel = ones(1,length(devices));
            app.Spinner.Value = i_channel(app.DropDown.Value);
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            global i_channel;
            i_channel(app.DropDown.Value) = app.Spinner.Value;
        end

        % Button pushed function: send_wav_file_button
        function send_wav_file_buttonButtonPushed(app, event)
            S = app.FSK_code.Value;
            codes = str2num(S(:))';
            sampleRate = app.fs_edit.Value;
            windows_size = app.windows_size_edit.Value;
            f0 = app.f0_edit.Value;
            f1 = app.f1_edit.Value;
            data = modulator_FSK_new_version(codes, sampleRate, windows_size, f0, f1);
            
            %% 对声音样本额外的处理
            data = [zeros(1,100),data,zeros(1,100)];
            
            %% 写到当前目录的 FSK.wav
            audiowrite("FSK.wav", data, sampleRate);
            
            devices = app.DropDown.Items;
            
            text_value = [];
            
            for i=1:length(devices)
                devices_name_char = char(string(devices(i)));
                devices_id = strsplit(devices_name_char, ' ');
                devices_id = devices_id{1};
                command_str = "adb -s " + devices_id + " push ./FSK.wav /storage/emulated/0/FSK.wav";
                [~, message] = system(command_str);
                
                text_value{end+1} = char(command_str);
                text_value =[text_value, message];
            end
            
            app.TextArea.Value = text_value;
            
            %% 
        end

        % Button pushed function: start_calc_dist
        function start_calc_distButtonPushed(app, event)
            global i_channel;
            devices = app.DropDown.Items;
            
            text_value = [];
            positions = {};
            
            for i=1:length(devices)
                devices_name_char = char(string(devices(i)));
                devices_id = strsplit(devices_name_char, ' ');
                devices_id = devices_id{1};
                command_str = "adb -s " + devices_id + " pull /storage/emulated/0/record.wav " + "./" + i + ".wav";
                [~, message] = system(command_str);                
                sampleRate = app.fs_edit.Value;
                windows_size = app.windows_size_edit.Value;
                f0 = app.f0_edit.Value;
                f1 = app.f1_edit.Value;
                S = app.FSK_code.Value;
                codes = str2num(S(:))';
                
                position_of_premble = demodulator_new_version(i+".wav", sampleRate, windows_size, f0, f1, codes, 0, i_channel(i));
                
                positions{end+1} = position_of_premble;
                
                text_value{end+1} = char(command_str);
                text_value = [text_value, message];
                text_value{end+1} = num2str(position_of_premble);
            end
            
            position_device_1 = positions{1};
            position_device_2 = positions{2};
            
            for i=1:2:min(length(position_device_1), length(position_device_2))-1
            delta = (position_device_2(i+1) - position_device_2(i)) - (position_device_1(i+1) - position_device_1(i));
            delta = abs(delta);
            dist = app.msEditField.Value / 2 * delta / sampleRate * 1000;
            text_value{end+1} = char("[" + i + "]" + "BeepBeep delta = " + abs(delta) + ", dist - dAA - dBB = " + dist + " mm, dist = " + (dist + app.BeepdAAdBBmmEditField.Value) + " mm");
            end
            
            app.TextArea.Value = text_value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '选择设备';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = {};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '测距用声道';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Limits = [1 2];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.msEditFieldLabel = uilabel(app.UIFigure);
            app.msEditFieldLabel.Text = '声速  （单位:m/s）';
            app.msEditField = uieditfield(app.UIFigure, 'numeric');
            app.msEditField.Value = 340;
            app.BeepdAAdBBmmLabel = uilabel(app.UIFigure);
            app.BeepdAAdBBmmLabel.Text = 'Beep dAA+dBB值  (单位：mm)';
            app.BeepdAAdBBmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.start_calc_dist = uibutton(app.UIFigure, 'push');
            app.start_calc_dist.ButtonPushedFcn = createCallbackFcn(app, @start_calc_distButtonPushed, true);
            app.start_calc_dist.Text = '已在手机端录音并发送完测距信号，开始计算';
            app.refresh_devices = uibutton(app.UIFigure, 'push');
            app.refresh_devices.ButtonPushedFcn = createCallbackFcn(app, @refresh_devicesPushed, true);
            app.refresh_devices.Text = '刷新设备';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '测距信号频率0';
            app.f0_edit = uieditfield(app.UIFigure, 'numeric');
            app.f0_edit.Limits = [0 Inf];
            app.f0_edit.ValueDisplayFormat = '%d';
            app.f0_edit.Value = 4000;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '频率1';
            app.f1_edit = uieditfield(app.UIFigure, 'numeric');
            app.f1_edit.Limits = [1 Inf];
            app.f1_edit.Value = 6000;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '窗口长度';
            app.windows_size_edit = uieditfield(app.UIFigure, 'numeric');
            app.windows_size_edit.Limits = [1 Inf];
            app.windows_size_edit.ValueDisplayFormat = '%d';
            app.windows_size_edit.Value = 1200;
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '采样率';
            app.fs_edit = uieditfield(app.UIFigure, 'numeric');
            app.fs_edit.ValueDisplayFormat = '%d';
            app.fs_edit.Editable = 'off';
            app.fs_edit.Value = 48000;
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '信号代码（仅01）';
            app.FSK_code = uieditfield(app.UIFigure, 'text');
            app.FSK_code.Value = '010101010101010101010010000001110011011001010110111001100100';
            app.send_wav_file_button = uibutton(app.UIFigure, 'push');
            app.send_wav_file_button.ButtonPushedFcn = createCallbackFcn(app, @send_wav_file_buttonButtonPushed, true);
            app.send_wav_file_button.Text = '发送wav文件到设备';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = beepbeep

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end