classdef run_part2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Label                  matlab.ui.control.Label
        TextArea               matlab.ui.control.TextArea
        Label_2                matlab.ui.control.Label
        packetSizeEditField    matlab.ui.control.NumericEditField
        f0Label                matlab.ui.control.Label
        f0EditField            matlab.ui.control.NumericEditField
        f1EditFieldLabel       matlab.ui.control.Label
        f1EditField            matlab.ui.control.NumericEditField
        Button                 matlab.ui.control.Button
        Label_3                matlab.ui.control.Label
        TextArea_2             matlab.ui.control.TextArea
        Button_2               matlab.ui.control.Button
        fsLabel                matlab.ui.control.Label
        fsEditField            matlab.ui.control.NumericEditField
        Label_4                matlab.ui.control.Label
        windowsSizeEditField   matlab.ui.control.NumericEditField
        Label_6                matlab.ui.control.Label
        HeaderLengthEditField  matlab.ui.control.NumericEditField
        Label_7                matlab.ui.control.Label
        prembleEditField       matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            str_to_send = char(app.TextArea.Value);
            packet_size = app.packetSizeEditField.Value;
            fs = app.fsEditField.Value;
            windows_size = app.windowsSizeEditField.Value;
            f0 = app.f0EditField.Value;
            f1 = app.f1EditField.Value;
            S = app.prembleEditField.Value;
            header_length = app.HeaderLengthEditField.Value;
            premble_codes = str2num(S(:))';
            len = length(str_to_send);
            disp(len);
            for i=1:packet_size:len
                cur_str = str_to_send(i:min(i+packet_size-1,len));
                send_str_new_version(strcat('[str]',cur_str,'[str]'), fs, windows_size, f0, f1, premble_codes, header_length, 'send.wav');
                [data, fs] = audioread('send.wav');
                player = audioplayer(data, fs);
                playblocking(player);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '待发送文本';
            app.TextArea = uitextarea(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '数据包字节数';
            app.packetSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.packetSizeEditField.Limits = [1 Inf];
            app.packetSizeEditField.Value = 20;
            app.f0Label = uilabel(app.UIFigure);
            app.f0Label.Text = '频率f0';
            app.f0EditField = uieditfield(app.UIFigure, 'numeric');
            app.f0EditField.Limits = [1 Inf];
            app.f0EditField.Value = 4000;
            app.f1EditFieldLabel = uilabel(app.UIFigure);
            app.f1EditFieldLabel.Text = '频率f1';
            app.f1EditField = uieditfield(app.UIFigure, 'numeric');
            app.f1EditField.Limits = [1 Inf];
            app.f1EditField.Value = 6000;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '发送文本';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '接收到的文本';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '接收文本';
            app.fsLabel = uilabel(app.UIFigure);
            app.fsLabel.Text = 'fs（采样频率）';
            app.fsEditField = uieditfield(app.UIFigure, 'numeric');
            app.fsEditField.Limits = [1 Inf];
            app.fsEditField.ValueDisplayFormat = '%d';
            app.fsEditField.Value = 48000;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '窗口长度';
            app.windowsSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.windowsSizeEditField.Value = 300;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '包头长度';
            app.HeaderLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeaderLengthEditField.Limits = [1 Inf];
            app.HeaderLengthEditField.Value = 12;
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '前缀码';
            app.prembleEditField = uieditfield(app.UIFigure, 'text');
            app.prembleEditField.Value = '010101010101010101010101010101010101010101010101010101010101';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = run_part2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end